classdef PIETOOLS_2021a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PIETOOLS2021aUIFigure  matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        AddnewBCButton         matlab.ui.control.Button
        AddtoPanel             matlab.ui.container.Panel
        DynamicsButton         matlab.ui.control.Button
        LHSDropDown            matlab.ui.control.DropDown
        BCNumberButton         matlab.ui.control.Button
        BCNumber               matlab.ui.control.DropDown
        ORLabel                matlab.ui.control.Label
        ORLabel_2              matlab.ui.control.Label
        OutputButton           matlab.ui.control.Button
        LHSDropDown_output     matlab.ui.control.DropDown
        AddTermPanel           matlab.ui.container.Panel
        Coefficient            matlab.ui.control.EditField
        ProductList            matlab.ui.control.DropDown
        TermType               matlab.ui.control.DropDown
        IntVariable            matlab.ui.control.DropDown
        Label                  matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        IndepVar               matlab.ui.control.DropDown
        intLabel               matlab.ui.control.Label
        intLowerLimit          matlab.ui.control.DropDown
        intUpperLimit          matlab.ui.control.DropDown
        d_sLabel               matlab.ui.control.Label
        derivativeOrder        matlab.ui.control.NumericEditField
        ConverttoPIEButton     matlab.ui.control.Button
        CenterPanel            matlab.ui.container.Panel
        TextArea               matlab.ui.control.TextArea
        TextArea_2             matlab.ui.control.TextArea
        Eqns                   matlab.ui.control.UIAxes
        RightPanel             matlab.ui.container.Panel
        AddVar                 matlab.ui.control.Button
        VarType                matlab.ui.control.DropDown
        RemoveButton           matlab.ui.control.Button
        VarTable               matlab.ui.control.Table
        UndoButton             matlab.ui.control.Button
        RedoButton             matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function InitializeGUI(app)
            set(app.LHSDropDown,'Items',{});
            set(app.ProductList,'Items',{});
            boldStyle = uistyle('FontWeight','bold');
            VarTypes = {'PDE State:', 'Highest Spatial Derivative:'; 
                         'ODE State:', ''
                         'Disturbance Input:', ''
                         'Control Input:', ''
                         'Regulated Output:', ''
                         'Observed Output:', ''};
            set(app.VarTable,'Data',VarTypes);
            addStyle(app.VarTable,boldStyle, 'cell',[1,1;1,2;2,1;3,1;4,1;5,1;6,1]);
            datatmp = struct('headerLoc', [1,1;2,1;3,1;4,1;5,1;6,1], 'SelectCell',[]);
            set(app.VarTable,'UserData',datatmp);
            
            set(app.intLabel,'Visible','off');
            set(app.intLowerLimit,'Visible','off');
            set(app.intUpperLimit,'Visible','off');
            set(app.IntVariable,'Visible','off');
            set(app.d_sLabel,'Visible','off');
            set(app.derivativeOrder,'Visible','off');
            
            initEqns = {'$\textbf{PDE Dynamics:}$';'$\textbf{ODE Dynamics:}$';...
                '$\textbf{Regulated Output:}$'; '$\textbf{Observed Output:}$'; '$\textbf{Boundary Conditions:}$'};
            locs = [1;2;3;4;5];
            set(app.TextArea,'Value',initEqns);
            set(app.TextArea,'UserData',locs);
            cla(app.Eqns);
            text(app.Eqns,0,1,initEqns,'VerticalAlignment','top','interpreter','latex');
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PIETOOLS2021aUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {519, 519, 519};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {519, 519};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {322, '1x', 251};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: DynamicsButton
        function addTerm2Dynamics(app, event)
            termType = get(app.TermType,'Value');
            coeff = get(app.Coefficient,'Value');
            stateterm = get(app.ProductList,'Value');
            stateArg = get(app.IndepVar, 'Value');
            
            a = get(app.intLowerLimit,'Value');
            b = get(app.intUpperLimit, 'Value');
            intvar = get(app.IntVariable, 'Value');
            
            LHS = get(app.LHSDropDown,'Value');
            dynamicstype = strrep(LHS,'\partial_t ', '');
            
            
            headerLoc = get(app.VarTable,'UserData');
            selectdata = headerLoc.SelectCell;
            headerLoc = headerLoc.headerLoc;
            
            allVars = get(app.VarTable,'Data');
            
            PDEvarlist = allVars(headerLoc(1,1)+1:headerLoc(2,1)-1,:);
            ODEvarlist = allVars(headerLoc(2,1)+1:headerLoc(3,1)-1,:);
            Disturbvarlist = allVars(headerLoc(3,1)+1:headerLoc(4,1)-1,:);
            Inputvarlist = allVars(headerLoc(4,1)+1:headerLoc(5,1)-1,:);
            RegOutvarlist = allVars(headerLoc(5,1)+1:headerLoc(6,1)-1,:);
            ObsOutvarlist = allVars(headerLoc(6,1)+1:end,:);

            if any(strcmp(ODEvarlist(:,1),dynamicstype)) 
                dynamicstype='ODE';
            elseif any(strcmp(PDEvarlist(:,1),dynamicstype))
                dynamicstype='PDE';
            end
            
            logicalval = isvalidPolynomial(coeff);
            validTerm = isvalidTerm(coeff,termType,dynamicstype,stateArg,stateterm,intvar);
            
            coeff = replace(coeff,'theta','\theta');
            
            if logicalval==0 && validTerm==0
                textDyn = get(app.TextArea,'Value');
                idx = find(contains(textDyn,LHS));
                existingTxt = textDyn{idx};
                
                if strcmp(existingTxt(end-3:end),'= 0$')
                    existingTxt=existingTxt(1:end-2);
                else
                    existingTxt=existingTxt(1:end-1);
                end
                
                if strcmp(termType,'Multiplier')
                    if strcmp(existingTxt(end-1:end),'= ')
                        firstPart = strcat(existingTxt,['(',coeff,')*']);
                    else
                        firstPart = strcat(existingTxt,['+(',coeff,')*']);
                    end
                    idx2 = find(contains(PDEvarlist(:,1),stateterm));
                    highestDer = PDEvarlist{idx2,2};
                    if (strcmp(stateArg,'s')||strcmp(stateArg,'\theta'))&&...
                            ((get(app.derivativeOrder,'Value')>0) && ...
                            get(app.derivativeOrder,'Value')<=highestDer(1))
                        secondPart = strcat('\partial_s^',num2str(get(app.derivativeOrder,'Value')));
                    elseif (strcmp(stateArg,'0')||strcmp(stateArg,'1'))&&...
                            ((get(app.derivativeOrder,'Value')>0) && ...
                            get(app.derivativeOrder,'Value')<highestDer(1))
                        secondPart = strcat('\partial_s^',num2str(get(app.derivativeOrder,'Value')));
                    elseif get(app.derivativeOrder,'Value')==0
                        secondPart = '';
                    else 
                        tmp=errordlg("Specified derivative in the term to be added is higher than" + ...
                            "highest allowed derivative for that state");
                        secondPart = '';
                    end
                    
                    replaceTxt = strcat(firstPart,secondPart,stateterm,'(',stateArg,')$');
                    textDyn{idx} = replaceTxt;
                else
                    if strcmp(existingTxt(end-1:end),'= ')
                        firstPart = strcat(existingTxt,['\int_',a,'^',b,'(',coeff,')*']);
                    else
                        firstPart = strcat(existingTxt,['+','\int_',a,'^',b,'(',coeff,')*']);
                    end
                    idx2 = find(contains(PDEvarlist(:,1),stateterm));
                    highestDer = PDEvarlist{idx2,2};
                    if (get(app.derivativeOrder,'Value')>0) && ...
                            get(app.derivativeOrder,'Value')<=highestDer(1)
                        secondPart = strcat('\partial_s^',num2str(get(app.derivativeOrder,'Value')));
                    elseif get(app.derivativeOrder,'Value')==0
                        secondPart = '';
                    else 
                        secondPart = '';
                        tmp=errordlg("Specified derivative in the term to be added is higher than" + ...
                            "highest allowed derivative for that state");
                    end
                    
                    replaceTxt = strcat(firstPart,secondPart,stateterm,'(',stateArg,')',intvar,'$');
                    textDyn{idx} = replaceTxt;
                end
                
                set(app.TextArea,'Value',textDyn);
                textDyn=erase(textDyn,'*');
                cla(app.Eqns);
                text(app.Eqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
            elseif validTerm~=0
                tmp = errordlg("This term cannot be added to the selected equation");
            else
                if logicalval==2
                    tmp = errordlg("Input term has unidentified polynomial variable. All variables must be " + ...
                      "polynomials in 's' or 'theta'.");
                elseif logicalval==3
                    tmp = errordlg("Input term has polynomial variable with non-integer exponent");
                else
                    tmp = errordlg("Input is not a valid polynomial");
                end
            end
        end

        % Button pushed function: BCNumberButton
        function addTerm2BC(app, event)
            termType = get(app.TermType,'Value');
            coeff = get(app.Coefficient,'Value');
            stateterm = get(app.ProductList,'Value');
            stateArg = get(app.IndepVar, 'Value');
            
            a = get(app.intLowerLimit,'Value');
            b = get(app.intUpperLimit, 'Value');
            intvar = get(app.IntVariable, 'Value');
            
            LHS = get(app.BCNumber,'Value');
            
            
            headerLoc = get(app.VarTable,'UserData');
            headerLoc = headerLoc.headerLoc;
            
            allVars = get(app.VarTable,'Data');
            
            PDEvarlist = allVars(headerLoc(1,1)+1:headerLoc(2,1)-1,:);
            ODEvarlist = allVars(headerLoc(2,1)+1:headerLoc(3,1)-1,:);
            Disturbvarlist = allVars(headerLoc(3,1)+1:headerLoc(4,1)-1,:);
            Inputvarlist = allVars(headerLoc(4,1)+1:headerLoc(5,1)-1,:);
            RegOutvarlist = allVars(headerLoc(5,1)+1:headerLoc(6,1)-1,:);
            ObsOutvarlist = allVars(headerLoc(6,1)+1:end,:);
            
            logicalval = isvalidPolynomial(coeff);
            validTerm = isvalidTerm(coeff,termType,'BC',stateArg,stateterm,intvar);
            
            coeff = replace(coeff,'theta','\theta');
            
            
            if logicalval==0 && validTerm==0
                
                textDyn = get(app.TextArea,'Value');
                eqnLoc = get(app.TextArea,'UserData');
                idx = str2double(LHS)+eqnLoc(end);
                existingTxt = textDyn{idx};
                
                if strcmp(existingTxt(end-3:end),'= 0$')
                    existingTxt=existingTxt(1:end-2);
                else
                    existingTxt=existingTxt(1:end-1);
                end
                
                if strcmp(termType,'Multiplier')
                    if strcmp(existingTxt(end-1:end),'= ')
                        firstPart = strcat(existingTxt,['(',coeff,')*']);
                    else
                        firstPart = strcat(existingTxt,['+(',coeff,')*']);
                    end
                    idx2 = find(contains(PDEvarlist(:,1),stateterm));
                    highestDer = PDEvarlist{idx2,2};
                    if (strcmp(stateArg,'s')||strcmp(stateArg,'\theta'))&&...
                            ((get(app.derivativeOrder,'Value')>0) && ...
                            get(app.derivativeOrder,'Value')<=highestDer(1))
                        secondPart = strcat('\partial_s^',num2str(get(app.derivativeOrder,'Value')));
                    elseif (strcmp(stateArg,'0')||strcmp(stateArg,'1'))&&...
                            ((get(app.derivativeOrder,'Value')>0) && ...
                            get(app.derivativeOrder,'Value')<highestDer(1))
                        secondPart = strcat('\partial_s^',num2str(get(app.derivativeOrder,'Value')));
                    elseif get(app.derivativeOrder,'Value')==0
                        secondPart = '';
                    else 
                        tmp=errordlg("Specified derivative in the term to be added is higher than" + ...
                            "highest allowed derivative for that state");
                        secondPart = '';
                    end
                    
                    replaceTxt = strcat(firstPart,secondPart,stateterm,'(',stateArg,')$');
                    textDyn{idx} = replaceTxt;
                else
                    if strcmp(existingTxt(end-1:end),'= ')
                        firstPart = strcat(existingTxt,['\int_',a,'^',b,'(',coeff,')*']);
                    else
                        firstPart = strcat(existingTxt,['+','\int_',a,'^',b,'(',coeff,')*']);
                    end
                    idx2 = find(contains(PDEvarlist(:,1),stateterm));
                    highestDer = PDEvarlist{idx2,2};
                    if (get(app.derivativeOrder,'Value')>0) && ...
                            get(app.derivativeOrder,'Value')<=highestDer(1)
                        secondPart = strcat('\partial_s^',num2str(get(app.derivativeOrder,'Value')));
                    elseif get(app.derivativeOrder,'Value')==0
                        secondPart = '';
                    else 
                        secondPart = '';
                        tmp=errordlg("Specified derivative in the term to be added is higher than" + ...
                            "highest allowed derivative for that state");
                    end
                    
                    replaceTxt = strcat(firstPart,secondPart,stateterm,'(',stateArg,')',intvar,'$');
                    textDyn{idx} = replaceTxt;
                end
                set(app.TextArea,'Value',textDyn);
                textDyn=erase(textDyn,'*');
                cla(app.Eqns);
                text(app.Eqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
            elseif validTerm~=0
                tmp = errordlg("This term cannot be added to the selected equation");
            else
                if logicalval==2
                    tmp = errordlg("Input term has unidentified polynomial variable");
                elseif logicalval==3
                    tmp = errordlg("Input term has polynomial variable with non-integer exponent");
                else
                    tmp = errordlg("Input is not a valid polynomial");
                end
            end
        end

        % Button pushed function: AddnewBCButton
        function addBC(app, event)
            textBC = get(app.TextArea,'Value');
            textBC{end+1} = ['$0 = 0$'];
            BClist = get(app.BCNumber,'Items');
            BClist{end+1} = num2str(length(BClist)+1);
            
            set(app.BCNumber,'Items',BClist);
            set(app.TextArea,'Value',textBC);
            textBC=erase(textBC,'*');
            cla(app.Eqns);
            text(app.Eqns,0,1,textBC,'VerticalAlignment','top','interpreter','latex');
        end

        % Button pushed function: AddVar
        function addnewVar(app, event)
            headerLoc = get(app.VarTable,'UserData');
            selectdata = headerLoc.SelectCell;
            headerLoc = headerLoc.headerLoc;
            
            allVars = get(app.VarTable,'Data');
            
            PDEvarlist = allVars(headerLoc(1,1)+1:headerLoc(2,1)-1,:);
            ODEvarlist = allVars(headerLoc(2,1)+1:headerLoc(3,1)-1,:);
            Disturbvarlist = allVars(headerLoc(3,1)+1:headerLoc(4,1)-1,:);
            Inputvarlist = allVars(headerLoc(4,1)+1:headerLoc(5,1)-1,:);
            RegOutvarlist = allVars(headerLoc(5,1)+1:headerLoc(6,1)-1,:);
            ObsOutvarlist = allVars(headerLoc(6,1)+1:end,:);
            
            if isempty(PDEvarlist)
                PDEvarlist = zeros([0,2]);
            end
            
            if strcmp(get(app.VarType, 'Value'),'PDE State')
                if ~isempty(PDEvarlist)
                    var_name = PDEvarlist{end,1};
                    var_name = ['X' num2str(str2double(var_name(2:end))+1)];
                else
                    var_name = 'X1';
                end
                allVars(headerLoc(2,1)+1:end+1,:) = allVars(headerLoc(2,1):end,:);
                allVars{headerLoc(2,1),1} = var_name;
                allVars{headerLoc(2,1),2} = 0;
                set(app.VarTable,'Data', allVars);
                
                headerLoc(2:end,1) = headerLoc(2:end,1) +1;
                datatmp = struct('headerLoc',headerLoc,'SelectCell',selectdata);
                set(app.VarTable,'UserData',datatmp);
                
                state_name_t = ['\partial_t ',var_name];
                dynamics_list = get(app.LHSDropDown,'Items');
                dynamics_list{end+1} = state_name_t;
                set(app.LHSDropDown,'Items', dynamics_list);
                
                textDyn = get(app.TextArea,'Value');
                locs = get(app.TextArea,'UserData');
                textDyn(locs(2)+1:end+1) = textDyn(locs(2):end);
                textDyn{locs(2)} = ['$', state_name_t,' = 0$'];
                locs(2:end) = locs(2:end)+1;
                set(app.TextArea,'Value',textDyn);
                set(app.TextArea,'UserData',locs);
                
                textDyn=erase(textDyn,'*');
                cla(app.Eqns);
                text(app.Eqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
                
                RHS_terms_list = get(app.ProductList, 'Items');
                RHS_terms_list{end+1} = var_name;
                set(app.ProductList,'Items', RHS_terms_list);
            elseif strcmp(get(app.VarType, 'Value'),'ODE State')
                if ~isempty(ODEvarlist)
                    var_name = ODEvarlist{end,1};
                    var_name = ['x' num2str(str2double(var_name(2:end))+1)];
                else
                    var_name = 'x1';
                end
                allVars(headerLoc(3,1)+1:end+1,:) = allVars(headerLoc(3,1):end,:);
                allVars(headerLoc(3,1),1) = {var_name};
                set(app.VarTable,'Data', allVars);
                
                headerLoc(3:end,1) = headerLoc(3:end,1) +1;
                datatmp = struct('headerLoc',headerLoc,'SelectCell',selectdata);
                set(app.VarTable,'UserData',datatmp);


                state_name_t = ['\partial_t ',var_name];
                dynamics_list = get(app.LHSDropDown,'Items');
                dynamics_list{end+1} = state_name_t;
                set(app.LHSDropDown,'Items', dynamics_list);
                
                textDyn = get(app.TextArea,'Value');
                locs = get(app.TextArea,'UserData');
                textDyn(locs(3)+1:end+1) = textDyn(locs(3):end);
                textDyn{locs(3)} = ['$', state_name_t,' = 0$'];
                locs(3:end) = locs(3:end)+1;
                set(app.TextArea,'Value',textDyn);
                set(app.TextArea,'UserData',locs);
                
                textDyn=erase(textDyn,'*');
                cla(app.Eqns);
                text(app.Eqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
                
                RHS_terms_list = get(app.ProductList, 'Items');
                RHS_terms_list{end+1} = var_name;
                set(app.ProductList,'Items', RHS_terms_list);
            elseif strcmp(get(app.VarType, 'Value'),'Input')
                if ~isempty(Inputvarlist)
                    var_name = Inputvarlist{end,1};
                    var_name = ['u' num2str(str2double(var_name(2:end))+1)];
                else
                    var_name = 'u1';
                end
                allVars(headerLoc(5,1)+1:end+1,:) = allVars(headerLoc(5,1):end,:);
                allVars{headerLoc(5,1),1} = var_name;
                set(app.VarTable,'Data', allVars);
                
                headerLoc(5:end,1) = headerLoc(5:end,1) +1;
                datatmp = struct('headerLoc',headerLoc,'SelectCell',selectdata);
                set(app.VarTable,'UserData',datatmp);
                
                RHS_terms_list = get(app.ProductList, 'Items');
                RHS_terms_list{end+1} = var_name;
                set(app.ProductList,'Items', RHS_terms_list);
            elseif strcmp(get(app.VarType, 'Value'),'Disturbance')
                if ~isempty(Disturbvarlist)
                    var_name = Disturbvarlist{end,1};
                    var_name = ['w' num2str(str2double(var_name(2:end))+1)];
                else
                    var_name = 'w1';
                end
                allVars(headerLoc(4,1)+1:end+1,:) = allVars(headerLoc(4,1):end,:);
                allVars{headerLoc(4,1),1} = var_name;
                set(app.VarTable,'Data', allVars);
                
                headerLoc(4:end,1) = headerLoc(4:end,1) +1;
                datatmp = struct('headerLoc',headerLoc,'SelectCell',selectdata);
                set(app.VarTable,'UserData',datatmp);
                
                RHS_terms_list = get(app.ProductList, 'Items');
                RHS_terms_list{end+1} = var_name;
                set(app.ProductList,'Items', RHS_terms_list);
            elseif strcmp(get(app.VarType, 'Value'),'Regulated Output')
                if ~isempty(RegOutvarlist)
                    var_name = RegOutvarlist{end,1};
                    var_name = ['z' num2str(str2double(var_name(2:end))+1)];
                else
                    var_name = 'z1';
                end
                allVars(headerLoc(6,1)+1:end+1,:) = allVars(headerLoc(6,1):end,:);
                allVars{headerLoc(6,1),1} = var_name;
                set(app.VarTable,'Data', allVars);
                
                headerLoc(6:end,1) = headerLoc(6:end,1) +1;
                datatmp = struct('headerLoc',headerLoc,'SelectCell',selectdata);
                set(app.VarTable,'UserData',datatmp);
                
                output_list = get(app.LHSDropDown_output,'Items');
                output_list{end+1} = var_name;
                set(app.LHSDropDown_output,'Items', output_list);
                
                textDyn = get(app.TextArea,'Value');
                locs = get(app.TextArea,'UserData');
                textDyn(locs(4)+1:end+1) = textDyn(locs(4):end);
                textDyn{locs(4)} = ['$', var_name,' = 0$'];
                locs(4:end) = locs(4:end)+1;
                set(app.TextArea,'Value',textDyn);
                set(app.TextArea,'UserData',locs);
                
                textDyn=erase(textDyn,'*');
                cla(app.Eqns);
                text(app.Eqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
            else 
                if ~isempty(ObsOutvarlist)
                    var_name = ObsOutvarlist{end,1};
                    var_name = ['y' num2str(str2double(var_name(2:end))+1)];
                else
                    var_name = 'y1';
                end
                allVars{end+1,1} = var_name;
                set(app.VarTable,'Data', allVars);
                
                datatmp = struct('headerLoc',headerLoc,'SelectCell',selectdata);
                set(app.VarTable,'UserData',datatmp);
                
                output_list = get(app.LHSDropDown_output,'Items');
                output_list{end+1} = var_name;
                set(app.LHSDropDown_output,'Items', output_list);
                
                textDyn = get(app.TextArea,'Value');
                locs = get(app.TextArea,'UserData');
                textDyn(locs(5)+1:end+1) = textDyn(locs(5):end);
                textDyn{locs(5)} = ['$', var_name,' = 0$'];
                locs(5:end) = locs(5:end)+1;
                set(app.TextArea,'Value',textDyn);
                set(app.TextArea,'UserData',locs);
                
                textDyn=erase(textDyn,'*');
                cla(app.Eqns);
                text(app.Eqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
            end
            removeStyle(app.VarTable);
            boldStyle = uistyle('FontWeight','bold');
            addStyle(app.VarTable,boldStyle,'cell',[headerLoc;[headerLoc(1,1) 2]]);
            
            allList = get(app.ProductList,'Items');
            set(app.ProductList,'UserData',allList);
        end

        % Button pushed function: OutputButton
        function addTerm2Output(app, event)
            termType = get(app.TermType,'Value');
            coeff = get(app.Coefficient,'Value');
            stateterm = get(app.ProductList,'Value');
            stateArg = get(app.IndepVar, 'Value');
            
            a = get(app.intLowerLimit,'Value');
            b = get(app.intUpperLimit, 'Value');
            intvar = get(app.IntVariable, 'Value');
            
            LHS = get(app.LHSDropDown_output,'Value');
            
            headerLoc = get(app.VarTable,'UserData');
            headerLoc = headerLoc.headerLoc;
            
            allVars = get(app.VarTable,'Data');
            
            PDEvarlist = allVars(headerLoc(1,1)+1:headerLoc(2,1)-1,:);
            ODEvarlist = allVars(headerLoc(2,1)+1:headerLoc(3,1)-1,:);
            Disturbvarlist = allVars(headerLoc(3,1)+1:headerLoc(4,1)-1,:);
            Inputvarlist = allVars(headerLoc(4,1)+1:headerLoc(5,1)-1,:);
            RegOutvarlist = allVars(headerLoc(5,1)+1:headerLoc(6,1)-1,:);
            ObsOutvarlist = allVars(headerLoc(6,1)+1:end,:);
            
            logicalval = isvalidPolynomial(coeff);
            validTerm = isvalidTerm(coeff,termType,'ODE',stateArg,stateterm,intvar);
            
            coeff = replace(coeff,'theta','\theta');
            if logicalval==0 && validTerm==0
                textDyn = get(app.TextArea,'Value');
                idx = find(contains(textDyn,LHS));
                existingTxt = textDyn{idx};
                
                if strcmp(existingTxt(end-3:end),'= 0$')
                    existingTxt=existingTxt(1:end-2);
                else
                    existingTxt=existingTxt(1:end-1);
                end
                
                if strcmp(termType,'Multiplier')
                    if strcmp(existingTxt(end-1:end),'= ')
                        firstPart = strcat(existingTxt,['(',coeff,')*']);
                    else
                        firstPart = strcat(existingTxt,['+(',coeff,')*']);
                    end
                    idx2 = find(contains(PDEvarlist(:,1),stateterm));
                    highestDer = PDEvarlist{idx2,2};
                    if (strcmp(stateArg,'s')||strcmp(stateArg,'\theta'))&&...
                            ((get(app.derivativeOrder,'Value')>0) && ...
                            get(app.derivativeOrder,'Value')<=highestDer(1))
                        secondPart = strcat('\partial_s^',num2str(get(app.derivativeOrder,'Value')));
                    elseif (strcmp(stateArg,'0')||strcmp(stateArg,'1'))&&...
                            ((get(app.derivativeOrder,'Value')>0) && ...
                            get(app.derivativeOrder,'Value')<highestDer(1))
                        secondPart = strcat('\partial_s^',num2str(get(app.derivativeOrder,'Value')));
                    elseif get(app.derivativeOrder,'Value')==0
                        secondPart = '';
                    else 
                        tmp=errordlg("Specified derivative in the term to be added is higher than" + ...
                            "highest allowed derivative for that state");
                        secondPart = '';
                    end
                    
                    replaceTxt = strcat(firstPart,secondPart,stateterm,'(',stateArg,')$');
                    textDyn{idx} = replaceTxt;
                else
                    if strcmp(existingTxt(end-1:end),'= ')
                        firstPart = strcat(existingTxt,['\int_',a,'^',b,'(',coeff,')*']);
                    else
                        firstPart = strcat(existingTxt,['+','\int_',a,'^',b,'(',coeff,')*']);
                    end
                    idx2 = find(contains(PDEvarlist(:,1),stateterm));
                    highestDer = PDEvarlist{idx2,2};
                    if (get(app.derivativeOrder,'Value')>0) && ...
                            get(app.derivativeOrder,'Value')<=highestDer(1)
                        secondPart = strcat('\partial_s^',num2str(get(app.derivativeOrder,'Value')));
                    elseif get(app.derivativeOrder,'Value')==0
                        secondPart = '';
                    else 
                        secondPart = '';
                        tmp=errordlg("Specified derivative in the term to be added is higher than" + ...
                            "highest allowed derivative for that state");
                    end
                    
                    replaceTxt = strcat(firstPart,secondPart,stateterm,'(',stateArg,')',intvar,'$');
                    textDyn{idx} = replaceTxt;
                end
                set(app.TextArea,'Value',textDyn);
                textDyn=erase(textDyn,'*');
                cla(app.Eqns);
                text(app.Eqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
            elseif validTerm~=0
                tmp = errordlg("This term cannot be added to the selected equation");
            else
                if logicalval==2
                    tmp = errordlg("Input term has unidentified polynomial variable");
                elseif logicalval==3
                    tmp = errordlg("Input term has polynomial variable with non-integer exponent");
                else
                    tmp = errordlg("Input is not a valid polynomial");
                end
            end
        end

        % Button pushed function: ConverttoPIEButton
        function convert2PIE(app, event)
            PDEvars = get(app.PDElist,'Data');
            ODEvars = get(app.ODElist,'Data');
            disvars = get(app.Disturblist,'Data');
            inpvars = get(app.Inputlist,'Data');
            regvars = get(app.RegOutlist,'Data');
            obsvars = get(app.ObsOutlist,'Data');
            pdetext= executive_pde_text_cleaner(get(app.TextArea,'Value'),PDEvars,...
                ODEvars, disvars,inpvars,regvars,obsvars);
            bctext = executive_pde_text_cleaner(get(app.TextArea_2,'Value'),PDEvars,...
                ODEvars, disvars,inpvars,regvars,obsvars);
            pdetext = pdetext(2:end);
            bctext = bctext(2:end);
            PDE_obj = executive_pde_parser(pdetext,bctext, size(ODEvars,1),...
                PDEvars(:,2),size(disvars,1),size(inpvars,2),...
                size(regvars,1),size(obsvars,1));
        end

        % Button pushed function: RemoveButton
        function removeVar(app, event)
            D=get(app.VarTable,'Data');
            Index=get(app.VarTable,'UserData');
            if ~isempty(Index.SelectCell)
                SelectedCell = Index.SelectCell; row2Remove = SelectedCell(1);
                headerlocs = Index.headerLoc; headerrows = headerlocs(:,1);
                if all(~ismember(row2Remove, headerrows))
                    % remove dynamics and output eqns
                    LHSlist = get(app.LHSDropDown,'Items');
                    LHSlist(contains(LHSlist,D(row2Remove,1))) = [];
                    set(app.LHSDropDown,'Items',LHSlist);
                    LHSlist = get(app.LHSDropDown_output,'Items');
                    LHSlist(contains(LHSlist,D(row2Remove,1))) = [];
                    set(app.LHSDropDown_output,'Items',LHSlist);
                    
                    RHSlist = get(app.ProductList,'Items');
                    RHSlist(contains(RHSlist,D(row2Remove,1))) = [];
                    set(app.ProductList,'Items',RHSlist);
                    RHSlist = get(app.ProductList,'UserData');
                    RHSlist(contains(RHSlist,D(row2Remove,1))) = [];
                    set(app.ProductList,'UserData',RHSlist);
                    
                    textDyn = get(app.TextArea,'Value');
                    locA = find(contains(textDyn,['$\partial_t ', D{row2Remove,1}, ' =']));
                    locB = find(contains(textDyn,['$', D{row2Remove,1}, ' =']));
                    locs = get(app.TextArea,'UserData');
                    
                    textDyn(locA) = [];
                    textDyn(locB) = [];
                    
                    if ~isempty(locA)
                        locs(locs>locA) = locs(locs>locA)-1;
                    elseif ~isempty(locB)
                        locs(locs>locB) = locs(locs>locB)-1;
                    end
                    
                    set(app.TextArea,'UserData',locs);
                    set(app.TextArea,'Value',textDyn);
                    
                    textDyn=erase(textDyn,'*');
                    cla(app.Eqns);
                    text(app.Eqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
                    
                    
                    % remove from var list
                    D(row2Remove,:)=[];
                    set(app.VarTable,'Data',D);
                    headerlocs(headerrows>row2Remove) = headerlocs(headerrows>row2Remove)-1;
                    datatmp = struct('headerLoc',headerlocs,'SelectCell',SelectedCell);
                    set(app.VarTable,'UserData',datatmp);
                end
            end
            removeStyle(app.VarTable);
            boldStyle = uistyle('FontWeight','bold');
            addStyle(app.VarTable,boldStyle,'cell',[headerlocs;[headerlocs(1,1) 2]]);
        end

        % Cell selection callback: VarTable
        function varSelectCallback(app, event)
            indices = event.Indices;
            tmpstruct = get(app.VarTable,'UserData');
            tmpstruct.SelectCell = indices;
            set(app.VarTable,'UserData',tmpstruct);
        end

        % Value changed function: TermType
        function termTypeChange(app, event)
            value = app.TermType.Value;
            if strcmp(value,'Multiplier')
                set(app.intLabel,'Visible','off');
                set(app.intLowerLimit,'Visible','off');
                set(app.intUpperLimit,'Visible','off');
                set(app.IntVariable,'Visible','off');
                set(app.ProductList,'Items',get(app.ProductList,'UserData'));
            else
                allList = get(app.ProductList,'Items');
                set(app.ProductList,'UserData',allList);
                set(app.intLabel,'Visible','on');
                set(app.intLowerLimit,'Visible','on');
                set(app.intUpperLimit,'Visible','on');
                set(app.IntVariable,'Visible','on');
                
                headerLoc = get(app.VarTable,'UserData');
                headerLoc = headerLoc.headerLoc;
                allVars = get(app.VarTable,'Data');
                PDEvarlist = allVars(headerLoc(1,1)+1:headerLoc(2,1)-1,:);
                PDEvarlist(:,2) = [];
                set(app.ProductList,'Items',PDEvarlist);
            end
        end

        % Value changed function: ProductList
        function stateTermTypeChange(app, event)
            value = app.ProductList.Value;
            headerLoc = get(app.VarTable,'UserData');
            selectdata = headerLoc.SelectCell;
            headerLoc = headerLoc.headerLoc;
            
            allVars = get(app.VarTable,'Data');
            
            PDEvarlist = allVars(headerLoc(1,1)+1:headerLoc(2,1)-1,:);
            
            if any(contains(PDEvarlist(:,1),value))
                set(app.d_sLabel,'Visible','on');
                set(app.derivativeOrder,'Visible','on');
            else
                set(app.d_sLabel,'Visible','off');
                set(app.derivativeOrder,'Visible','off');
            end
        end

        % Cell edit callback: VarTable
        function changeDerivative(app, event)
            indices = event.Indices;
            newData = event.NewData;
            headerLoc = get(app.VarTable,'UserData');
            selectdata = headerLoc.SelectCell;
            headerLoc = headerLoc.headerLoc;
            
            allVars = get(app.VarTable,'Data');
            
            if indices(1)>headerLoc(1,1) && indices(1)<headerLoc(2,1)
                allVars{indices(1),indices(2)} = newData;
            else
                allVars{indices(1),indices(2)} = event.PreviousData;
            end
            set(app.VarTable,'Data',allVars);
        end

        % Button pushed function: UndoButton
        function undoPress(app, event)
            previousStates = get(app.UndoButton,'UserData');
            futureStates = get(app.RedoButton,'UserData');
            
            tmp = app;
            
            futureStates(2:end) = futureStates(1:end-1); 
            futureStates(1) = tmp;
            
            previousStates(2:end) = previousStates(1:end-1);
            previousStates(1) = previousStates(2);
            
            app = previousStates(end);
            set(app.UndoButton,'UserData',previousStates);
            set(app.RedoButton,'UserData',futureStates);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PIETOOLS2021aUIFigure = uifigure('Visible', 'off');
            app.PIETOOLS2021aUIFigure.Name = 'PIETOOLS 2021a';
            app.PIETOOLS2021aUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PIETOOLS2021aUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AddnewBCButton = uibutton(app.LeftPanel, 'push');
            app.AddnewBCButton.ButtonPushedFcn = createCallbackFcn(app, @addBC, true);
            app.AddnewBCButton.Tag = 'AddDynamicsTerms';
            app.AddnewBCButton.Text = 'Add new BC';
            app.AddtoPanel = uipanel(app.LeftPanel);
            app.AddtoPanel.Title = 'Add to:';
            app.DynamicsButton = uibutton(app.AddtoPanel, 'push');
            app.DynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @addTerm2Dynamics, true);
            app.DynamicsButton.Tag = 'AddDynamicsTerms';
            app.DynamicsButton.Text = 'Dynamics';
            app.LHSDropDown = uidropdown(app.AddtoPanel);
            app.LHSDropDown.Items = {};
            app.LHSDropDown.Value = {};
            app.BCNumberButton = uibutton(app.AddtoPanel, 'push');
            app.BCNumberButton.ButtonPushedFcn = createCallbackFcn(app, @addTerm2BC, true);
            app.BCNumberButton.Tag = 'AddDynamicsTerms';
            app.BCNumberButton.Text = 'BC Number';
            app.BCNumber = uidropdown(app.AddtoPanel);
            app.BCNumber.Items = {};
            app.BCNumber.Value = {};
            app.ORLabel = uilabel(app.AddtoPanel);
            app.ORLabel.Text = 'OR';
            app.ORLabel_2 = uilabel(app.AddtoPanel);
            app.ORLabel_2.Text = 'OR';
            app.OutputButton = uibutton(app.AddtoPanel, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @addTerm2Output, true);
            app.OutputButton.Tag = 'AddDynamicsTerms';
            app.OutputButton.Text = 'Output';
            app.LHSDropDown_output = uidropdown(app.AddtoPanel);
            app.LHSDropDown_output.Items = {};
            app.LHSDropDown_output.Value = {};
            app.AddTermPanel = uipanel(app.LeftPanel);
            app.AddTermPanel.Title = 'Add Term';
            app.Coefficient = uieditfield(app.AddTermPanel, 'text');
            app.Coefficient.Tag = 'Coefficient';
            app.ProductList = uidropdown(app.AddTermPanel);
            app.ProductList.ValueChangedFcn = createCallbackFcn(app, @stateTermTypeChange, true);
            app.ProductList.Tag = 'Int_Mul_state';
            app.TermType = uidropdown(app.AddTermPanel);
            app.TermType.Items = {'Multiplier', 'Integral', ''};
            app.TermType.ValueChangedFcn = createCallbackFcn(app, @termTypeChange, true);
            app.TermType.Value = 'Multiplier';
            app.IntVariable = uidropdown(app.AddTermPanel);
            app.IntVariable.Items = {'ds', 'd\theta'};
            app.IntVariable.Tag = 'intVariable';
            app.IntVariable.Value = 'ds';
            app.Label = uilabel(app.AddTermPanel);
            app.Label.Text = '(';
            app.Label_2 = uilabel(app.AddTermPanel);
            app.Label_2.Text = ')';
            app.IndepVar = uidropdown(app.AddTermPanel);
            app.IndepVar.Items = {'s', '\theta', '0', '1'};
            app.IndepVar.Value = 's';
            app.intLabel = uilabel(app.AddTermPanel);
            app.intLabel.Text = 'int';
            app.intLowerLimit = uidropdown(app.AddTermPanel);
            app.intLowerLimit.Items = {'0', 's'};
            app.intLowerLimit.Value = '0';
            app.intUpperLimit = uidropdown(app.AddTermPanel);
            app.intUpperLimit.Items = {'s', '1'};
            app.intUpperLimit.Value = 's';
            app.d_sLabel = uilabel(app.AddTermPanel);
            app.d_sLabel.Text = 'd_s';
            app.derivativeOrder = uieditfield(app.AddTermPanel, 'numeric');
            app.ConverttoPIEButton = uibutton(app.LeftPanel, 'push');
            app.ConverttoPIEButton.ButtonPushedFcn = createCallbackFcn(app, @convert2PIE, true);
            app.ConverttoPIEButton.Enable = 'off';
            app.ConverttoPIEButton.Visible = 'off';
            app.ConverttoPIEButton.Text = 'Convert to PIE';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TextArea = uitextarea(app.CenterPanel);
            app.TextArea.Tag = 'Dynamics';
            app.TextArea.Visible = 'off';
            app.TextArea_2 = uitextarea(app.CenterPanel);
            app.TextArea_2.Tag = 'BCs';
            app.TextArea_2.Visible = 'off';
            app.Eqns = uiaxes(app.CenterPanel);
            title(app.Eqns, '')
            xlabel(app.Eqns, '')
            ylabel(app.Eqns, '')
            app.Eqns.XAxisLocation = 'top';
            app.Eqns.XTick = [];
            app.Eqns.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.AddVar = uibutton(app.RightPanel, 'push');
            app.AddVar.ButtonPushedFcn = createCallbackFcn(app, @addnewVar, true);
            app.AddVar.Text = 'Add';
            app.VarType = uidropdown(app.RightPanel);
            app.VarType.Items = {'PDE State', 'ODE State', 'Disturbance', 'Input', 'Regulated Output', 'Observed Output'};
            app.VarType.Value = 'PDE State';
            app.RemoveButton = uibutton(app.RightPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @removeVar, true);
            app.RemoveButton.Text = 'Remove';
            app.VarTable = uitable(app.RightPanel);
            app.VarTable.ColumnName = {''};
            app.VarTable.RowName = {};
            app.VarTable.ColumnEditable = true;
            app.VarTable.CellEditCallback = createCallbackFcn(app, @changeDerivative, true);
            app.VarTable.CellSelectionCallback = createCallbackFcn(app, @varSelectCallback, true);
            app.UndoButton = uibutton(app.RightPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @undoPress, true);
            app.UndoButton.Text = 'Undo';
            app.RedoButton = uibutton(app.RightPanel, 'push');
            app.RedoButton.Text = 'Redo';
            % Show the figure after all components are created
            app.PIETOOLS2021aUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIETOOLS_2021a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PIETOOLS2021aUIFigure)

            % Execute the startup function
            runStartupFcn(app, @InitializeGUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PIETOOLS2021aUIFigure)
        end
    end
end